classdef VirtualSensorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VirtualSensorAppUIFigure  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        ImportTab                 matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        VariablesListBox          matlab.ui.control.ListBox
        VariablesListBoxLabel     matlab.ui.control.Label
        ImportFileButton          matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        ModelTab                  matlab.ui.container.Tab
        GridLayout                matlab.ui.container.GridLayout
        ProgressBar               wt.ProgressBar
        UITable                   matlab.ui.control.Table
        TrainModelButton          matlab.ui.control.Button
        PredictTab                matlab.ui.container.Tab
        ModelstatusLabel          matlab.ui.control.Label
        PredictButton             matlab.ui.control.Button
        UIAxes2_2                 matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % data read from the user-defined file
        responseColor = [0.90,0.91,0.83];
        predictorColor = [0.83,0.91,0.84];
        notUsedColor = [0.91,0.83,0.83];
        model
        prediction
        predictors = {'FuelQuantity', 'OilPressure', 'OilTemperature',...
                      'Altitude', 'ExhaustTemperature', 'FuelFlow',...
                      'FanSpeed', 'WindDirection', 'WindSpeed'}; % used by the model
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx','*.csv'},'Choose file');
            fpath = fullfile(path,file);
            if ~isempty(file)
                try
                    app.data = importFlightData(fpath);
                    app.data = app.data(app.data.TrueAirSpeed > 10,:);
                    vars = app.data.Properties.VariableNames;
                    app.VariablesListBox.Items = vars(2:end);
                catch me
                    msgbox("Error: " + me.message)
                end
                app.TrainModelButton.Enable = "on";
            end
        end

        % Value changed function: VariablesListBox
        function VariablesListBoxValueChanged(app, event)
            value = app.VariablesListBox.Value;
            plot(app.UIAxes,app.data.Time,app.data{:,value})
            xlabel(app.UIAxes,"Time");
            ylabel(app.UIAxes,value);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

            if selectedTab.Title == "Model"

                if ~isempty(app.data)
                    app.UITable.Data = app.data.Properties.VariableNames(2:end)';


                    rowColors = repmat(app.notUsedColor, size(app.UITable.Data, 1), 1);
                    app.UITable.Data(:,2) = cellstr(repmat("Not used",12,1));
                    for i = 1:length(app.UITable.Data)
                        if ismember(app.UITable.Data{i}, app.predictors)
                            rowColors(i,:) = app.predictorColor;
                            app.UITable.Data{i,2} = 'Predictor';
                        elseif ismember(app.UITable.Data{i}, 'TrueAirSpeed')
                            rowColors(i,:) = app.responseColor;
                            app.UITable.Data{i,2} = 'Response';
                        end
                    end
                    app.UITable.BackgroundColor = rowColors;
                end
            elseif selectedTab.Title == "Predict"
                if isempty(app.model)
                    app.PredictButton.Enable = "off";
                    app.ModelstatusLabel.Text = "Model status: not trained";
                else
                    app.PredictButton.Enable = "on";
                    app.ModelstatusLabel.Text = "Model status: trained";
                end
            end
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            if ~isempty(app.data)
                try
                    cleanedData = cleanOilSensors(app.data);
                    app.ProgressBar.Indeterminate = true;
                    app.ProgressBar.startProgress("Training model...");
                    app.model = trainTASModel(cleanedData);
                    app.ProgressBar.finishProgress("Model training finished");
                    app.ProgressBar.Indeterminate = false;
                catch me
                    msgbox("Error: " + me.message)
                end
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            if ~isempty(app.model)
                try 
                    app.prediction = app.model.predictFcn(cleanOilSensors(app.data));
                    hold(app.UIAxes2,'on')
                    cleanData = cleanOilSensors(app.data);
                    plot(app.UIAxes2,cleanData.Time,cleanData.TrueAirSpeed)
                    plot(app.UIAxes2,cleanData.Time,app.prediction)
                    hold(app.UIAxes2,"off")
                    legend(app.UIAxes2,"Actual","Predicted")
                    plot(app.UIAxes2_2,cleanData.TrueAirSpeed-app.prediction)
                    legend("Residual")
                catch me
                    msgbox("Something went wrong when predicting response")
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VirtualSensorAppUIFigure = uifigure('Visible', 'off');
            app.VirtualSensorAppUIFigure.Name = 'Virtual Sensor App';
            app.TabGroup = uitabgroup(app.VirtualSensorAppUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.GridLayout2 = uigridlayout(app.ImportTab);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ImportFileButton = uibutton(app.GridLayout2, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.VariablesListBoxLabel = uilabel(app.GridLayout2);
            app.VariablesListBoxLabel.Text = 'Variables';
            app.VariablesListBox = uilistbox(app.GridLayout2);
            app.VariablesListBox.Items = {};
            app.VariablesListBox.ValueChangedFcn = createCallbackFcn(app, @VariablesListBoxValueChanged, true);
            app.VariablesListBox.Value = {};
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.GridLayout = uigridlayout(app.ModelTab);
            app.TrainModelButton = uibutton(app.GridLayout, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Enable = 'off';
            app.TrainModelButton.Text = 'Train Model';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Variables'; 'Type'};
            app.UITable.RowName = {};
            app.ProgressBar = wt.ProgressBar(app.GridLayout);
            app.PredictTab = uitab(app.TabGroup);
            app.PredictTab.Title = 'Predict';
            app.UIAxes2 = uiaxes(app.PredictTab);
            title(app.UIAxes2, 'Response')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.PredictTab);
            title(app.UIAxes2_2, 'Residual')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.PredictButton = uibutton(app.PredictTab, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.ModelstatusLabel = uilabel(app.PredictTab);
            app.ModelstatusLabel.Text = 'Model status: ';
            % Show the figure after all components are created
            app.VirtualSensorAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VirtualSensorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VirtualSensorAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VirtualSensorAppUIFigure)
        end
    end
end